classdef CalciumProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        LoadImagesROITab  matlab.ui.container.Tab
        AddCellsButton    matlab.ui.control.Button
        right             matlab.ui.control.Button
        left              matlab.ui.control.Button
        LoadFileButton    matlab.ui.control.Button
        Label             matlab.ui.control.Label
        ImageSlider       matlab.ui.control.Slider
        ImageLabel        matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
        Tab2              matlab.ui.container.Tab
        InputCutsButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        myFolder    
        filePattern
        tifFiles   
        play
        roi
        means
        numImages
        cuts
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes,'DataAspectRatio',[2 2 1])
            app.roi = {};
            app.cuts = {};
            
            app.numImages = 0;
        end

        % Button pushed function: LoadFileButton
        function LoadFIle(app, event)
            try % Random capitalized I, *why*
                app.myFolder = uigetdir(pwd);  % get file
                app.filePattern = fullfile(app.myFolder + "\GFP", '*.tif'); % gets all the tif files
                app.tifFiles = dir(app.filePattern);
                
                app.numImages = length(app.tifFiles); % takes length and adjusts the slider
                app.ImageSlider.Limits = [1 app.numImages];
                
                set(app.ImageSlider, 'value', 1) % sets slider
                baseFileName = app.tifFiles(1).name; % gets specific image
                fullFileName = fullfile(app.myFolder + "\GFP", baseFileName);
                imageArray = imread(fullFileName);
                app.Label.Text = baseFileName; % displays image on axis and displays name
                % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
                % Display image and stretch to fill axes
                
                I = imshow(imageArray, 'Parent', app.UIAxes, ... 
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                drawnow;
            catch
                warning("There was an error in selecting your files. Please try again.");
            end
        end

        % Button pushed function: left
        function leftPressed(app, event)
            if not(app.numImages)
                return;
            end
            
            y = max(app.ImageSlider.Value-1, 1);
            set(app.ImageSlider, 'value', y)
            
            file = app.tifFiles(round(y)); % why is there a round y?
            filePath = fullfile(file.folder, file.name);
            % baseFileName = file.name; 
            % fullFileName = fullfile(app.myFolder + "\GFP", baseFileName);
            imageArray = imread(filePath);
            app.Label.Text = file.name;
            
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ... % dunno what this does, oh well
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            if not(app.numImages)
                return;
            end
            
            y = min(app.ImageSlider.Value+1, app.numImages);
            set(app.ImageSlider, 'value', y)
            
            file = app.tifFiles(round(y)); % why is there a round y?
            filePath = fullfile(file.folder, file.name);
            % baseFileName = file.name; 
            % fullFileName = fullfile(app.myFolder + "\GFP", baseFileName);
            imageArray = imread(filePath);
            app.Label.Text = file.name;
            
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ... 
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            
            % Set limits of axes            
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end

        % Callback function
        function PlayButtonPushed(app, event)
            app.play = not(app.play);
        end

        % Value changing function: ImageSlider
        function ImageSliderValueChanging(app, event)
            if not(app.numImages)
                return;
            end
            
            y = event.Value;
            
            file = app.tifFiles(round(y)); % why is there a round y?
            filePath = fullfile(file.folder, file.name);
            % baseFileName = file.name; 
            % fullFileName = fullfile(app.myFolder + "\GFP", baseFileName);
            imageArray = imread(filePath);
            app.Label.Text = file.name;
            
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow; % Force display to update immediately.
        end

        % Button pushed function: AddCellsButton
        function AddCellsButtonPushed(app, event)
            answer = str2double(inputdlg("How many cells are in the Image?")); % takes number
            for i = 1:answer                
                app.Label.Text = "Draw Cell " + i;
                app.roi{i} = drawpolygon(app.UIAxes, "FaceAlpha", 0); %draws polygon
            end
            app.Label.Text = "Draw backgorund";
            app.roi{answer+1} = drawpolygon(app.UIAxes, "FaceAlpha", 0); %draws polygon
            
            x = app.ImageSlider.Value;
            baseFileName = app.tifFiles(round(x)).name;
            app.Label.Text = baseFileName;
            
            app.means = zeros(app.numImages, answer+1);
            
            for i = 1:app.numImages
                baseFileName = app.tifFiles(i).name;
                fullFileName = fullfile(app.myFolder + "\GFP", baseFileName);
                imageArray = imread(fullFileName); %gets the image since regionprops doesn't work with UIAxes
                for j = 1:answer+1
                    mask = createMask(app.roi{j});
                    avgintensity = struct2array(regionprops(mask, imageArray,"MeanIntensity")); % gets the mean intensity for the mask within the image
                    % disp(avgintensity);
                    app.means(i, j) = avgintensity(1);
                    
                end
            end
            disp(app.means)
            writematrix(app.means, 'Result.csv');
            
        end

        % Callback function
        function TrimButtonPushed(app, event)
            outputFolder = uigetdir('C:'); % takes in a directory for output
            answer = inputdlg("number of files in output"); % takes number
            trimfiles(app.myFolder + "\GFP", outputFolder, str2double(answer)); % calls function
            
            app.myFolder = outputFolder;
            app.filePattern = fullfile(app.myFolder, '*.tif');
            app.tifFiles = dir(app.filePattern);
            app.play = false;
            
            len = length(app.tifFiles);
            app.ImageSlider.Limits = [0 len-1];
            
            set(app.ImageSlider, 'value', 1)
            baseFileName = app.tifFiles(1).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end

        % Button pushed function: InputCutsButton
        function InputCutsButtonPushed(app, event)
            answer = str2double(inputdlg("How many cuts are there"));
            for i = 1:answer
                app.cuts{i} = str2double(inputdlg("Which image is the cut"));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadImagesROITab = uitab(app.TabGroup);
            app.LoadImagesROITab.Title = 'Load Images + ROI';
            app.UIAxes = uiaxes(app.LoadImagesROITab);
            app.ImageLabel = uilabel(app.LoadImagesROITab);
            app.ImageLabel.Text = 'Image';
            app.ImageSlider = uislider(app.LoadImagesROITab);
            app.ImageSlider.Limits = [0 200];
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderValueChanging, true);
            app.Label = uilabel(app.LoadImagesROITab);
            app.Label.Text = '1';
            app.LoadFileButton = uibutton(app.LoadImagesROITab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFIle, true);
            app.LoadFileButton.Text = {'Load File'; ''};
            app.left = uibutton(app.LoadImagesROITab, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftPressed, true);
            app.left.Text = '<-';
            app.right = uibutton(app.LoadImagesROITab, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '->';
            app.AddCellsButton = uibutton(app.LoadImagesROITab, 'push');
            app.AddCellsButton.ButtonPushedFcn = createCallbackFcn(app, @AddCellsButtonPushed, true);
            app.AddCellsButton.Text = 'Add Cells';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.InputCutsButton = uibutton(app.Tab2, 'push');
            app.InputCutsButton.ButtonPushedFcn = createCallbackFcn(app, @InputCutsButtonPushed, true);
            app.InputCutsButton.Text = {'Input Cuts'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalciumProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end